classdef denoising < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        stopButton      matlab.ui.control.Button
        pauseButton     matlab.ui.control.Button
        playButton      matlab.ui.control.Button
        plotButton_2    matlab.ui.control.Button
        plotButton      matlab.ui.control.Button
        fft_audio       matlab.ui.control.UIAxes
        original_audio  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fileinput = 'C:\Users\poywa\Documents\MATLAB\za-warudo.mp3' ; 

    end
    
    
    methods (Access = private)
        
        function tranformAudio(app)


                [y,Fs] = audioread(app.fileinput); % y = data of the audio
                n = length(y);
                t = linspace(0,n/Fs,n); %time

                %% Compute FFT

                fhat = fft(y) ; % Compute the fast fourier transform
                PSD = fhat.*conj(fhat)/n ; 
                    
                %% Clear noise with indices
                indices = PSD>0.02; % Find all freqs with large power
                PSDclean = PSD.*indices; 
                fhat = indices.*fhat; %Zero out small Fourier coeffs. in Y
                ffilt = ifft(fhat); % Inverse FFT for filtered time signal

                plot(app.fft_audio,t,PSDclean);
                % sound(ffilt,Fs);

            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
     
            [y,Fs] = audioread(app.fileinput); % y = data of the audio
            plot(app.original_audio,y);
            % sound(y,Fs);
        end

        % Button pushed function: plotButton_2
        function plotButton_2Pushed(app, event)
            tranformAudio(app)
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.original_audio = uiaxes(app.UIFigure);
            title(app.original_audio, 'audio input')
            xlabel(app.original_audio, 'X')
            ylabel(app.original_audio, 'Y')
            zlabel(app.original_audio, 'Z')
            app.fft_audio = uiaxes(app.UIFigure);
            title(app.fft_audio, 'audio after fft')
            xlabel(app.fft_audio, 'X')
            ylabel(app.fft_audio, 'Y')
            zlabel(app.fft_audio, 'Z')
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.plotButton_2 = uibutton(app.UIFigure, 'push');
            app.plotButton_2.ButtonPushedFcn = createCallbackFcn(app, @plotButton_2Pushed, true);
            app.plotButton_2.Text = 'plot';
            app.playButton = uibutton(app.UIFigure, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'play';
            app.pauseButton = uibutton(app.UIFigure, 'push');
            app.pauseButton.Text = 'pause';
            app.stopButton = uibutton(app.UIFigure, 'push');
            app.stopButton.Text = 'stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = denoising

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end