classdef denoisingAddControlAudioButton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Slider          matlab.ui.control.Slider
        SliderLabel     matlab.ui.control.Label
        StopButton_2    matlab.ui.control.Button
        PauseButton_2   matlab.ui.control.Button
        PlayButton_2    matlab.ui.control.Button
        StopButton      matlab.ui.control.Button
        PauseButton     matlab.ui.control.Button
        PlayButton      matlab.ui.control.Button
        ImportButton    matlab.ui.control.Button
        afterDenoising  matlab.ui.control.UIAxes
        fft_audio       matlab.ui.control.UIAxes
        original_audio  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        player
        playerAfterDenoising
    end
    
    
    methods (Access = private)
        
        function tranformAudio(app)
                global fileinput
                [y,Fs] = audioread(fileinput); % y = data of the audio
                n = length(y);
                t = linspace(0,n/Fs,n); %time

                %% Compute FFT

                fhat = fft(y) ; % Compute the fast fourier transform
                PSD = fhat.*conj(fhat)/n ; 
                    
                %% Clear noise with indices
                indices = PSD>0.3; % Find all freqs with large power
                PSDclean = PSD.*indices; 
                fhat = indices.*fhat; %Zero out small Fourier coeffs. in Y
                ffilt = ifft(fhat); % Inverse FFT for filtered time signal

                plot(app.fft_audio,t,fhat);
                plot(app.afterDenoising,t,ffilt);

                app.playerAfterDenoising = audioplayer(ffilt,Fs);

                %%sound(ffilt,Fs);

            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function plotButtonPushed(app, event)
            global fileinput
            [y,Fs] = audioread(fileinput); % y = data of the audio
            plot(app.original_audio,y);
            app.player=audioplayer(y,Fs);
        end

        % Callback function: not associated with a component
        function plotButton_2Pushed(app, event)
            tranformAudio(app)
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            %% getfile audio
            global fileinput
            [filename,path]=uigetfile();
            fileinput = filename;
            
            %% Change Title file
            app.original_audio.Title.String = fileinput ; 
            app.fft_audio.Title.String = strcat(fileinput,"  after Fast fourier Tranform");
            
            app.afterDenoising.Title.String = strcat(fileinput,"  after Denoising");
            %% Plot after Import file
            %% Original audio
            [y,Fs] = audioread(fileinput); % y = data of the audio
            plot(app.original_audio,y);
            app.player=audioplayer(y/100,Fs);
            
            %% FFT audio and Denoised audio
            tranformAudio(app)


        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %% resume button
            resume(app.player)
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            %% pause button
            pause(app.player)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %% stop button
            stop(app.player)
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            resume(app.playerAfterDenoising)
        end

        % Button pushed function: PauseButton_2
        function PauseButton_2Pushed(app, event)
            pause(app.playerAfterDenoising)
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            stop(app.playerAfterDenoising)
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)

        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)

            global fileinput
            value = app.Slider.Value;

            pause(app.player);
            NewStart=get(app.player,'CurrentSample')+1;
            stop(app.player);
            [y,Fs] = audioread(fileinput);
            x=y(NewStart:end,:);
            app.player=audioplayer(x*value,Fs);
            play(app.player);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.original_audio = uiaxes(app.UIFigure);
            title(app.original_audio, 'Audio Input')
            xlabel(app.original_audio, 'X')
            ylabel(app.original_audio, 'Y')
            zlabel(app.original_audio, 'Z')
            app.fft_audio = uiaxes(app.UIFigure);
            title(app.fft_audio, 'Audio after Fast fourier Tranform')
            xlabel(app.fft_audio, 'X')
            ylabel(app.fft_audio, 'Y')
            zlabel(app.fft_audio, 'Z')
            app.afterDenoising = uiaxes(app.UIFigure);
            title(app.afterDenoising, 'Audio Denoising')
            xlabel(app.afterDenoising, 'X')
            ylabel(app.afterDenoising, 'Y')
            zlabel(app.afterDenoising, 'Z')
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.PauseButton_2 = uibutton(app.UIFigure, 'push');
            app.PauseButton_2.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_2Pushed, true);
            app.PauseButton_2.Text = 'Pause';
            app.StopButton_2 = uibutton(app.UIFigure, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-2 2];
            app.Slider.MajorTicks = [-2 -1 0 1 2];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [-2 -1.8 -1.6 -1.4 -1.2 -1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = denoisingAddControlAudioButton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end