classdef denoisingAddVolumeControl01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TextArea                    matlab.ui.control.TextArea
        TextAreaLabel               matlab.ui.control.Label
        VolumeSlider_3              matlab.ui.control.Slider
        VolumeSlider_3Label         matlab.ui.control.Label
        SetDenoiseValueButtonGroup  matlab.ui.container.ButtonGroup
        PSDisapowerspectrumofsignalLabel  matlab.ui.control.Label
        setButton                   matlab.ui.control.Button
        PSDEditField                matlab.ui.control.NumericEditField
        PSDEditFieldLabel           matlab.ui.control.Label
        PSDValueclearPSDgreaterthanValueButton  matlab.ui.control.RadioButton
        PSDValueclearPSDunderValueButton  matlab.ui.control.RadioButton
        VolumeSlider                matlab.ui.control.Slider
        VolumeSliderLabel           matlab.ui.control.Label
        StopButton_2                matlab.ui.control.Button
        PauseButton_2               matlab.ui.control.Button
        PlayButton_2                matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        PauseButton                 matlab.ui.control.Button
        PlayButton                  matlab.ui.control.Button
        ImportButton                matlab.ui.control.Button
        PsdGraph                    matlab.ui.control.UIAxes
        afterDenoising              matlab.ui.control.UIAxes
        fft_audio                   matlab.ui.control.UIAxes
        original_audio              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        player
        playerAfterDenoising
        resum = false ;
        setPSD = 0 ; % set default value of PSD
    end
    
    
    methods (Access = private)
        
        function tranformAudio(app)
                global fileinput
                [y,Fs] = audioread(fileinput); % y = data of the audio
                n = length(y);
                t = linspace(0,n/Fs,n); % time
                f = linspace(0,Fs,n); % frequency
 
                %% Compute FFT

                fhat = fft(y) ; % Compute the fast fourier transform
                PSD = fhat.*conj(fhat)/n ; 

                    
                %% Clear noise with indices
                app.setPSD = app.PSDEditField.Value ; 
                indices = PSD>app.setPSD; % Find all freqs with large power
                PSDclean = PSD.*indices; 
                %fhat = indices.*fhat; % Zero out small Fourier coeffs. in Y
                ffilt = ifft(fhat); % Inverse FFT for filtered time signal
                absfhat = abs(fhat);

                plot(app.fft_audio,f,absfhat);
                plot(app.afterDenoising,t,ffilt);
                plot(app.PsdGraph,PSDclean) ;

                app.playerAfterDenoising = audioplayer(ffilt,Fs);

                %%sound(ffilt,Fs);

            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function plotButtonPushed(app, event)
            global fileinput
            [y,Fs] = audioread(fileinput); % y = data of the audio
            plot(app.original_audio,y);
            app.player=audioplayer(y,Fs);
        end

        % Callback function: not associated with a component
        function plotButton_2Pushed(app, event)
            tranformAudio(app)
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            global fileinput 
            
            %% getfile audio
            [filename,path]=uigetfile();
            fileinput = filename;
            
            %% Change Title file
            app.original_audio.Title.String = fileinput ; 
            app.fft_audio.Title.String = strcat(fileinput,"  after Fast fourier Tranform");
            app.afterDenoising.Title.String = strcat(fileinput,"  after Denoising");
            %% Plot after Import file
            %% Original audio
            [y,Fs] = audioread(fileinput); % y = data of the audio
            n = length(y);
            t = linspace(0,n/Fs,n); %time
            plot(app.original_audio,t,y);
            %xlabel(app.original_audio,"time(t)")
            %ylabel(app.original_audio,"data")
            app.player=audioplayer(y,Fs);
            
            %% FFT audio and Denoised audio
            tranformAudio(app)


        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            global fileinput
            global value
            %% resume button
            try
               [y, Fs] = audioread(fileinput);
               app.player=audioplayer(y*value,Fs);
               % Play audio
               play(app.player)
          
            catch ME
                uiwait(msgbox('Could not open that file with Audioread'));
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            %% pause button
            if app.resum 
                resume(app.player)
                app.resum = false ;
            else 
                pause(app.player)
                app.resum = true ;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %% stop button
            stop(app.player)
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)

            resume(app.playerAfterDenoising)
        end

        % Button pushed function: PauseButton_2
        function PauseButton_2Pushed(app, event)
            pause(app.playerAfterDenoising)
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            stop(app.playerAfterDenoising)
        end

        % Value changing function: VolumeSlider
        function VolumeSliderValueChanging(app, event)

        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)

            global fileinput
            global value
            value = app.VolumeSlider.Value;
            [y,Fs] = audioread(fileinput);
            app.player=audioplayer(y*value,Fs);
        end

        % Button pushed function: setButton
        function setButtonPushed(app, event)
            tranformAudio(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.original_audio = uiaxes(app.UIFigure);
            title(app.original_audio, 'Audio Input')
            xlabel(app.original_audio, 'time(t)')
            ylabel(app.original_audio, 'data')
            zlabel(app.original_audio, 'Z')
            app.fft_audio = uiaxes(app.UIFigure);
            title(app.fft_audio, 'Audio after Fast fourier Tranform')
            xlabel(app.fft_audio, 'frequency(Hz)')
            ylabel(app.fft_audio, 'spectrum')
            zlabel(app.fft_audio, 'Z')
            app.afterDenoising = uiaxes(app.UIFigure);
            title(app.afterDenoising, 'Audio after Denoising')
            xlabel(app.afterDenoising, 'time(t)')
            ylabel(app.afterDenoising, 'data')
            zlabel(app.afterDenoising, 'Z')
            app.PsdGraph = uiaxes(app.UIFigure);
            title(app.PsdGraph, 'Power spectrum of Audio')
            xlabel(app.PsdGraph, 'frequency(Hz)')
            ylabel(app.PsdGraph, 'spectrum')
            zlabel(app.PsdGraph, 'Z')
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.PauseButton_2 = uibutton(app.UIFigure, 'push');
            app.PauseButton_2.ButtonPushedFcn = createCallbackFcn(app, @PauseButton_2Pushed, true);
            app.PauseButton_2.Text = 'Pause';
            app.StopButton_2 = uibutton(app.UIFigure, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.VolumeSliderLabel = uilabel(app.UIFigure);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.Limits = [0 2];
            app.VolumeSlider.MajorTicks = [0 1 2];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.ValueChangingFcn = createCallbackFcn(app, @VolumeSliderValueChanging, true);
            app.VolumeSlider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.VolumeSlider.Value = 1;
            app.SetDenoiseValueButtonGroup = uibuttongroup(app.UIFigure);
            app.SetDenoiseValueButtonGroup.Title = 'Set Denoise Value';
            app.PSDValueclearPSDunderValueButton = uiradiobutton(app.SetDenoiseValueButtonGroup);
            app.PSDValueclearPSDunderValueButton.Text = {'PSD > Value'; '(clear PSD under Value)'};
            app.PSDValueclearPSDunderValueButton.Value = true;
            app.PSDValueclearPSDgreaterthanValueButton = uiradiobutton(app.SetDenoiseValueButtonGroup);
            app.PSDValueclearPSDgreaterthanValueButton.Text = {'PSD < Value'; '(clear PSD greaterthan Value)'};
            app.PSDEditFieldLabel = uilabel(app.SetDenoiseValueButtonGroup);
            app.PSDEditFieldLabel.Text = 'PSD';
            app.PSDEditField = uieditfield(app.SetDenoiseValueButtonGroup, 'numeric');
            app.setButton = uibutton(app.SetDenoiseValueButtonGroup, 'push');
            app.setButton.ButtonPushedFcn = createCallbackFcn(app, @setButtonPushed, true);
            app.setButton.Text = 'set';
            app.PSDisapowerspectrumofsignalLabel = uilabel(app.SetDenoiseValueButtonGroup);
            app.PSDisapowerspectrumofsignalLabel.Text = '" PSD is a power spectrum of signal "';
            app.VolumeSlider_3Label = uilabel(app.UIFigure);
            app.VolumeSlider_3Label.Text = 'Volume';
            app.VolumeSlider_3 = uislider(app.UIFigure);
            app.VolumeSlider_3.Limits = [0 2];
            app.VolumeSlider_3.MajorTicks = [0 1 2];
            app.VolumeSlider_3.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.VolumeSlider_3.Value = 1;
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = denoisingAddVolumeControl01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end