classdef denoisingFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image_2                     matlab.ui.control.Image
        Image                       matlab.ui.control.Image
        VolumeSlider_3              matlab.ui.control.Slider
        ExportButton                matlab.ui.control.Button
        ClearAllButton              matlab.ui.control.Button
        SetDenoiseValueButtonGroup  matlab.ui.container.ButtonGroup
        PSDsetisdeleteamplitudethatlessthanyoursettingvalueLabel  matlab.ui.control.Label
        Thisappcanbedenoiseaudioaround1030dependonyourPSDsetLabel  matlab.ui.control.Label
        PSDisapowerspectrumofsignalLabel  matlab.ui.control.Label
        setButton                   matlab.ui.control.Button
        PSDEditField                matlab.ui.control.NumericEditField
        PSDEditFieldLabel           matlab.ui.control.Label
        VolumeSlider                matlab.ui.control.Slider
        Button_7                    matlab.ui.control.Button
        Button_6                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        ImportButton                matlab.ui.control.Button
        PsdGraph                    matlab.ui.control.UIAxes
        afterDenoising              matlab.ui.control.UIAxes
        fft_audio                   matlab.ui.control.UIAxes
        original_audio              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        player
        playerAfterDenoising
        resum = false ;
        setPSD = 0 ; % set default value of PSD
    end
    
    
    methods (Access = private)
        
        function tranformAudio(app)
                global fileinput ffilt Fs absfhat
                [y,Fs] = audioread(fileinput); % y = data of the audio
                l_data = length(y);
                t = linspace(0,l_data/Fs,l_data); % time
                n = length(t);
                freq = (0:n-1)*(Fs/n); % frequency
                freq0 = (-n/2:n/2-1)*(Fs/n); % 0-centered frequency range

 
                %% Compute FFT

                fhat = fft(y,n) ; % Compute the fast fourier transform
                PSD = fhat.*conj(fhat)/n ; % Multiply conj complex number to get real number

                    
                %% Clear noise with indices
                app.setPSD = app.PSDEditField.Value ; 
                indices = PSD>app.setPSD; % Find all freqs with large power
                PSDclean = PSD.*indices; 
                ffhat = indices.*fhat; % Zero out small Fourier coeffs. in Y
                fhat = fhat - ffhat ;
                ffilt = ifft(fhat); % Inverse FFT for filtered time signal
                absfhat = abs(ffhat);
                absfhat = fftshift(absfhat);

                %% Double filter

                ffilt = fft(ffilt,n) ; 
                PSD = ffilt.*conj(ffilt)/n ;
                app.setPSD = app.PSDEditField.Value ; 
                indices = PSD>app.setPSD*2; % Find all freqs with large power
                ffhat2 = indices.*ffilt; % Zero out small Fourier coeffs. in Y
                ffilt = ffilt - ffhat2 ;
                ffilt = ifft(ffilt);

                %% Plot graph

                plot(app.fft_audio,freq,absfhat,"Color","#D95319");
                plot(app.afterDenoising,t,ffilt,"Color","#D95319");
                plot(app.PsdGraph,freq0,PSDclean,"Color","#D95319");

                app.playerAfterDenoising = audioplayer(ffilt,Fs);

                %%sound(ffilt,Fs);

            
        end
        
        function clearAll(app)

            app.original_audio.Title.String = "Audio Input";
            app.fft_audio.Title.String = "Audio after Fast fourier Tranform" ; 
            app.afterDenoising.Title.String = "Audio after Denoising";
            app.PsdGraph.Title.String = "Power spectrum of Audio";
            
            app.VolumeSlider.Value = 1 ;
            app.VolumeSlider_3.Value = 1 ;
            app.PSDEditField.Value = 0;
            app.Button_3.Icon = "Icon\pauseIcon.png" ; 
            app.Button_6.Icon = "Icon\pauseIcon.png" ; 

            cla(app.original_audio)
            cla(app.fft_audio)
            cla(app.afterDenoising)
            cla(app.PsdGraph)


            app.Button_2.Enable = "off";
            app.Button_3.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
            app.Button_6.Enable = "off";
            app.Button_7.Enable = "off";
            app.ClearAllButton.Enable = "off";
            app.VolumeSlider.Enable = "off" ;
            app.VolumeSlider_3.Enable = "off" ;
            app.setButton.Enable = "off";
            app.ExportButton.Enable = "off";


        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            %% Clear all before new import
                
            clearAll(app)
            

            global fileinput filename

            try
            
            %% getfile audio
            [filename,path]=uigetfile();
            fileinput = strcat(path,filename); % Concat path and filename
            
            %% Change Title file
            %msgbox(fileinput)
            app.original_audio.Title.String = filename ; 
            app.fft_audio.Title.String = strcat(filename,"  after Fast fourier Tranform");
            app.afterDenoising.Title.String = strcat(filename,"  after Denoising");
            %% Plot after Import file
            %% Original audio
            [y,Fs] = audioread(fileinput); % y = data of the audio
            n = length(y);
            t = linspace(0,n/Fs,n); %time
            plot(app.original_audio,t,y);
            app.player=audioplayer(y,Fs);

            %% Enable button if not error
            
            app.Button_2.Enable = "on";
            app.Button_3.Enable = "on";
            app.Button_4.Enable = "on";
            app.Button_5.Enable = "on";
            app.Button_6.Enable = "on";
            app.Button_7.Enable = "on";
            app.ClearAllButton.Enable = "on";
            app.VolumeSlider.Enable = "on" ;
            app.VolumeSlider_3.Enable = "on" ;
            app.setButton.Enable = "on";
            app.ExportButton.Enable = "on";


            %% FFT audio and Denoised audio
            tranformAudio(app)

            catch
                msgbox("Cannot read this file please select the correct type of audio files")
                
            app.Button_2.Enable = "off";
            app.Button_3.Enable = "off";
            app.Button_4.Enable = "off";
            app.Button_5.Enable = "off";
            app.Button_6.Enable = "off";
            app.Button_7.Enable = "off";
            app.ClearAllButton.Enable = "off";
            app.VolumeSlider.Enable = "off" ;
            app.VolumeSlider_3.Enable = "off" ;
            app.setButton.Enable = "off";
            app.ExportButton.Enable = "off";

            end


        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

                play(app.player)
                app.Button_3.Icon = "Icon\pauseIcon.png" ; 
                app.resum = false ;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            %% pause button
            if app.resum 
                resume(app.player)
                app.Button_3.Icon = "Icon\pauseIcon.png";
                app.resum = false ;
            else 
                pause(app.player)
                app.Button_3.Icon = "Icon\resumeIcon.png";
                app.resum = true ;
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %% stop button
            stop(app.player)
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            
            play(app.playerAfterDenoising)
            app.Button_6.Icon = "Icon\pauseIcon.png" ; 
            app.resum = false ; 
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if app.resum 
                resume(app.playerAfterDenoising)
                app.Button_6.Icon = "Icon\pauseIcon.png";
                app.resum = false ;
            else 
                pause(app.playerAfterDenoising)
                app.Button_6.Icon = "Icon\resumeIcon.png";
                app.resum = true ;
            end
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            stop(app.playerAfterDenoising)
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)

            global fileinput
            global value
            value = app.VolumeSlider.Value;
            [y,Fs] = audioread(fileinput);
            app.player=audioplayer(y*value,Fs);
        end

        % Button pushed function: setButton
        function setButtonPushed(app, event)
            tranformAudio(app)
        end

        % Value changed function: VolumeSlider_3
        function VolumeSlider_3ValueChanged(app, event)
            global ffilt Fs
            global value_audio_after_denoise
            value_audio_after_denoise = app.VolumeSlider_3.Value;
            app.playerAfterDenoising=audioplayer(ffilt*value_audio_after_denoise,Fs);

            
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            clearAll(app)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            global ffilt Fs filename
            
            %% Change type of file before write

            path = "audioafterdenoise\"   ; 
            [f,b] = fileparts(filename);
            nb = sprintf('%s.wav',b) ; 
            newFullFileName = fullfile(f, nb) ; 


            %% Export audio file to audioafternoise folder

            audiowrite(strcat(path,newFullFileName),ffilt,Fs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.original_audio = uiaxes(app.UIFigure);
            title(app.original_audio, 'Audio Input')
            xlabel(app.original_audio, 'time(t)')
            ylabel(app.original_audio, 'Amplitude')
            zlabel(app.original_audio, 'Z')
            app.fft_audio = uiaxes(app.UIFigure);
            title(app.fft_audio, 'Audio after Fast fourier Tranform')
            xlabel(app.fft_audio, 'frequency(Hz)')
            ylabel(app.fft_audio, 'Amplitude')
            zlabel(app.fft_audio, 'Z')
            app.afterDenoising = uiaxes(app.UIFigure);
            title(app.afterDenoising, 'Audio after Denoising')
            xlabel(app.afterDenoising, 'time(t)')
            ylabel(app.afterDenoising, 'Amplitude')
            zlabel(app.afterDenoising, 'Z')
            app.PsdGraph = uiaxes(app.UIFigure);
            title(app.PsdGraph, 'Power spectrum of Audio')
            xlabel(app.PsdGraph, 'frequency(Hz)')
            ylabel(app.PsdGraph, 'Amplitude')
            zlabel(app.PsdGraph, 'Z')
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Icon = fullfile(pathToMLAPP, 'Icon', 'downloadIcon.png');
            app.ImportButton.Text = 'Import';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'Icon', 'playIcon.png');
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'Icon', 'pauseIcon.png');
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'Icon', 'stopIcon.png');
            app.Button_4.Enable = 'off';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = fullfile(pathToMLAPP, 'Icon', 'playIcon.png');
            app.Button_5.Enable = 'off';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = fullfile(pathToMLAPP, 'Icon', 'pauseIcon.png');
            app.Button_6.Enable = 'off';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = fullfile(pathToMLAPP, 'Icon', 'stopIcon.png');
            app.Button_7.Enable = 'off';
            app.Button_7.Text = '';
            app.VolumeSlider = uislider(app.UIFigure);
            app.VolumeSlider.Limits = [0 2];
            app.VolumeSlider.MajorTicks = [0 1 2];
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.VolumeSlider.Enable = 'off';
            app.VolumeSlider.Value = 1;
            app.SetDenoiseValueButtonGroup = uibuttongroup(app.UIFigure);
            app.SetDenoiseValueButtonGroup.Title = 'Set Denoise Value';
            app.PSDEditFieldLabel = uilabel(app.SetDenoiseValueButtonGroup);
            app.PSDEditFieldLabel.Text = 'PSD';
            app.PSDEditField = uieditfield(app.SetDenoiseValueButtonGroup, 'numeric');
            app.setButton = uibutton(app.SetDenoiseValueButtonGroup, 'push');
            app.setButton.ButtonPushedFcn = createCallbackFcn(app, @setButtonPushed, true);
            app.setButton.Enable = 'off';
            app.setButton.Text = 'set';
            app.PSDisapowerspectrumofsignalLabel = uilabel(app.SetDenoiseValueButtonGroup);
            app.PSDisapowerspectrumofsignalLabel.Text = '" PSD is a power spectrum of signal "';
            app.Thisappcanbedenoiseaudioaround1030dependonyourPSDsetLabel = uilabel(app.SetDenoiseValueButtonGroup);
            app.Thisappcanbedenoiseaudioaround1030dependonyourPSDsetLabel.Text = {'" This app can be denoise audio around 10-15% '; '   depend on type of noise and your PSD set "'};
            app.PSDsetisdeleteamplitudethatlessthanyoursettingvalueLabel = uilabel(app.SetDenoiseValueButtonGroup);
            app.PSDsetisdeleteamplitudethatlessthanyoursettingvalueLabel.Text = '" PSD set is delete amplitude that less than your setting value "';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Icon = fullfile(pathToMLAPP, 'Icon', 'clearall.png');
            app.ClearAllButton.Enable = 'off';
            app.ClearAllButton.Text = 'ClearAll';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Icon = fullfile(pathToMLAPP, 'Icon', 'exportIcon.png');
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.VolumeSlider_3 = uislider(app.UIFigure);
            app.VolumeSlider_3.Limits = [0 2];
            app.VolumeSlider_3.MajorTicks = [0 1 2];
            app.VolumeSlider_3.ValueChangedFcn = createCallbackFcn(app, @VolumeSlider_3ValueChanged, true);
            app.VolumeSlider_3.MinorTicks = [0 0.2 0.4 0.6 0.8 1 1.2 1.4 1.6 1.8 2];
            app.VolumeSlider_3.Enable = 'off';
            app.VolumeSlider_3.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Icon', 'speakerIcon.png');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.Enable = 'off';
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Icon', 'speakerIcon.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = denoisingFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end