classdef denoisingAddControlAudioButton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StopButton              matlab.ui.control.Button
        PauseButton             matlab.ui.control.Button
        PlayButton              matlab.ui.control.Button
        FileNameEditField       matlab.ui.control.EditField
        FileNameEditFieldLabel  matlab.ui.control.Label
        ImportButton            matlab.ui.control.Button
        plotButton_2            matlab.ui.control.Button
        plotButton              matlab.ui.control.Button
        fft_audio_2             matlab.ui.control.UIAxes
        fft_audio               matlab.ui.control.UIAxes
        original_audio          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        player
    end
    
    
    methods (Access = private)
        
        function tranformAudio(app)
                global fileinput
                [y,Fs] = audioread(fileinput); % y = data of the audio
                n = length(y);
                t = linspace(0,n/Fs,n); %time

                %% Compute FFT

                fhat = fft(y) ; % Compute the fast fourier transform
                PSD = fhat.*conj(fhat)/n ; 
                    
                %% Clear noise with indices
                indices = PSD>0.3; % Find all freqs with large power
                PSDclean = PSD.*indices; 
                fhat = indices.*fhat; %Zero out small Fourier coeffs. in Y
                ffilt = ifft(fhat); % Inverse FFT for filtered time signal

                plot(app.fft_audio,t,fhat);
                %%sound(ffilt,Fs);

            
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            global fileinput
            [y,Fs] = audioread(fileinput); % y = data of the audio
            plot(app.original_audio,y);
            app.player=audioplayer(y,Fs);
            play(app.player)
        end

        % Button pushed function: plotButton_2
        function plotButton_2Pushed(app, event)
            tranformAudio(app)
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            %% getfile audio
            global fileinput
            [filename,path]=uigetfile();
            fileinput = filename;
            app.FileNameEditField.Value=filename;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %% resume button
            resume(app.player)
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            %% pause button
            pause(app.player)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %% stop button
            stop(app.player)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.original_audio = uiaxes(app.UIFigure);
            title(app.original_audio, 'audio input')
            xlabel(app.original_audio, 'X')
            ylabel(app.original_audio, 'Y')
            zlabel(app.original_audio, 'Z')
            app.fft_audio = uiaxes(app.UIFigure);
            title(app.fft_audio, 'audio after fft')
            xlabel(app.fft_audio, 'X')
            ylabel(app.fft_audio, 'Y')
            zlabel(app.fft_audio, 'Z')
            app.fft_audio_2 = uiaxes(app.UIFigure);
            title(app.fft_audio_2, 'audio after fft')
            xlabel(app.fft_audio_2, 'X')
            ylabel(app.fft_audio_2, 'Y')
            zlabel(app.fft_audio_2, 'Z')
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot';
            app.plotButton_2 = uibutton(app.UIFigure, 'push');
            app.plotButton_2.ButtonPushedFcn = createCallbackFcn(app, @plotButton_2Pushed, true);
            app.plotButton_2.Text = 'plot';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = denoisingAddControlAudioButton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end